classdef App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        Make3DButton                  matlab.ui.control.Button
        LoadButton                    matlab.ui.control.Button
        SaveButton                    matlab.ui.control.Button
        RunConstructionButton         matlab.ui.control.Button
        HideRevealConstructionButton  matlab.ui.control.Button
        HideRevealLinesButton         matlab.ui.control.Button
        HideRevealPointsButton        matlab.ui.control.Button
        UIAxes                        matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        points double                 
        showPoints logical = true
        circles 
        showLines logical = true 
        showConstruction logical = true
        lineHandles
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: HideRevealPointsButton
        function PointsButton(app, event)
        
            hPoints1 = findobj(app.UIAxes, 'DisplayName', 'Intersections with Circle 1');
            hPoints2 = findobj(app.UIAxes, 'DisplayName', 'Intersections with Circle 2');
            hPoints3 = findobj(app.UIAxes, 'DisplayName', 'Transformed Intersections with Circle 1');
            hPoints4 = findobj(app.UIAxes, 'DisplayName', 'Transformed Intersections with Circle 2');

            hPoints = [hPoints1, hPoints2, hPoints3, hPoints4];
            
            if ~isempty(hPoints)

                if strcmp(hPoints(1).Visible, 'on')

                    set(hPoints, 'Visible', 'off'); 

                else

                    set(hPoints, 'Visible', 'on');  

                end
            end
        end

        % Button pushed function: HideRevealLinesButton
        function LinesButton(app, event)

        hBisector1 = findobj(app.UIAxes, 'DisplayName', 'First Perpendicular Bisector');
        hBisector2 = findobj(app.UIAxes, 'DisplayName', 'Second Perpendicular Bisector');
        hBisector3 = findobj(app.UIAxes, 'DisplayName', 'Third Perpendicular Bisector');

        hBisectors = [hBisector1; hBisector2; hBisector3];

        if ~isempty(hBisectors)

            if strcmp(hBisectors(1).Visible, 'on')

                set(hBisectors, 'Visible', 'off');  

            else

                set(hBisectors, 'Visible', 'on');   

            end
        end
        end

        % Button pushed function: HideRevealConstructionButton
        function ConstructionButton(app, event)

            hConstructions1 = findobj(app.UIAxes, 'DisplayName', 'First Circle');
            hConstructions2 = findobj(app.UIAxes, 'DisplayName', 'Second Circle');
            hConstructions3 = findobj(app.UIAxes, 'DisplayName', 'Third Circle');
            hConstructions4 = findobj(app.UIAxes, 'DisplayName', 'Transformed First Circle');
            hConstructions5 = findobj(app.UIAxes, 'DisplayName', 'Transformed Second Circle');
            hConstructions6 = findobj(app.UIAxes, 'DisplayName', 'Transformed Third Circle');

            hConstruction = [hConstructions1, hConstructions2, hConstructions3, hConstructions4, hConstructions5, hConstructions6];
        
            if ~isempty(hConstruction)

                if strcmp(hConstruction(1).Visible, 'on')

                    set(hConstruction, 'Visible', 'off');  

                else

                    set(hConstruction, 'Visible', 'on');  

                end
            end
        end

        % Button pushed function: RunConstructionButton
        function Run(app, event)

            if size(app.points, 1) < 6

                uialert(app.UIFigure, 'You need at least six points: three for the first two circles, two for snapping, and one for snapping to the bisector.', 'Error');

                return;

            end
        
            app.lineHandles = [];
        
            % first circle
            xCircle1 = app.points(end-5, 1);
            yCircle1 = app.points(end-5, 2);
            xCircumference1 = app.points(end-4, 1);
            yCircumference1 = app.points(end-4, 2);
            radiusCircle1 = sqrt((xCircumference1 - xCircle1)^2 + (yCircumference1 - yCircle1)^2);
        
            % second circle
            xCircle2 = app.points(end-3, 1);
            yCircle2 = app.points(end-3, 2);
            radiusCircle2 = radiusTangentialCircle(xCircle1, yCircle1, xCircumference1, yCircumference1, xCircle2, yCircle2);
        
            if radiusCircle2 <= 0
                uialert(app.UIFigure, 'Invalid second circle. Ensure the second center allows for tangential contact.', 'Error');
                return;
            end
        
            % third circle 
            xPoint1 = app.points(end-2, 1);
            yPoint1 = app.points(end-2, 2);
            xPoint2 = app.points(end-1, 1);
            yPoint2 = app.points(end-1, 2);
        
            % Closest Points on Circles
            [xOnCircle1, yOnCircle1, select1] = closestPointsOnCircle(xCircle1, yCircle1, radiusCircle1, xCircle2, yCircle2, radiusCircle2, xPoint1, yPoint1);
            [xOnCircle2, yOnCircle2, select2] = closestPointsOnCircle(xCircle1, yCircle1, radiusCircle1, xCircle2, yCircle2, radiusCircle2, xPoint2, yPoint2);
        
            if select1 == select2
                uialert(app.UIFigure, 'Selected points must snap to different circles.', 'Error');
                return;
            end
        
            % First Perpendicular Bisector
            [mBisector1, cBisector1] = centreBisector(xCircle1, yCircle1, xCircle2, yCircle2);
        
            % Point on Bisector
            xOffBisector = app.points(end, 1);
            yOffBisector = app.points(end, 2);
            [xOnBisector, yOnBisector] = closestPointBisector(mBisector1, cBisector1, xOffBisector, yOffBisector);
        
            % Solve for Third Circle
            [xCircle3, yCircle3, radiusCircle3] = circleSolve([xOnCircle1, yOnCircle1], [xOnCircle2, yOnCircle2], [xOnBisector, yOnBisector]);
        
            % Second Perpendicular Bisector
            [mBisector2, cBisector2] = centreBisector(xOnCircle1, yOnCircle1, xOnBisector, yOnBisector);
        
            % Plot Circles
            numCirclePoints = 300;
            theta = linspace(0, 2*pi, numCirclePoints);
            
            x1 = xCircle1 + radiusCircle1 * cos(theta);
            y1 = yCircle1 + radiusCircle1 * sin(theta);
            x2 = xCircle2 + radiusCircle2 * cos(theta);
            y2 = yCircle2 + radiusCircle2 * sin(theta);
            x3 = xCircle3 + radiusCircle3 * cos(theta);
            y3 = yCircle3 + radiusCircle3 * sin(theta);
            
            hold(app.UIAxes, 'on');
            
            % Number of animation steps (should match the number of points for smoothness)
            numSteps = numCirclePoints;
            
            % Draw First Circle smoothly
            hC1 = plot(app.UIAxes, x1(1), y1(1), 'b-', 'LineWidth', 1.5, 'DisplayName', 'First Circle');
            for seg = 2:numSteps
                set(hC1, 'XData', x1(1:seg), 'YData', y1(1:seg));
                drawnow;
                pause(0.001); % Adjust pause for desired speed
            end
            
            % Draw Second Circle smoothly
            hC2 = plot(app.UIAxes, x2(1), y2(1), 'r-', 'LineWidth', 1.5, 'DisplayName', 'Second Circle');
            for seg = 2:numSteps
                set(hC2, 'XData', x2(1:seg), 'YData', y2(1:seg));
                drawnow;
                pause(0.001);
            end
            
            % Draw Third Circle smoothly
            hC3 = plot(app.UIAxes, x3(1), y3(1), 'm-', 'LineWidth', 1.5, 'DisplayName', 'Third Circle');
            for seg = 2:numSteps
                set(hC3, 'XData', x3(1:seg), 'YData', y3(1:seg));
                drawnow;
                pause(0.001);
            end

            axis(app.UIAxes, 'equal');

            % Plot First Perpendicular Bisector
                xBisector1 = linspace(0, 100, 100);
            if ~isinf(mBisector1)
                yBisector1 = mBisector1 * xBisector1 + cBisector1;
            else
                xBisector1 = cBisector1 * ones(size(xBisector1));
                yBisector1 = linspace(0, 100, 100);
            end
            app.lineHandles(1) = plot(app.UIAxes, xBisector1, yBisector1, 'g--', 'LineWidth', 1.2, 'DisplayName', 'First Perpendicular Bisector');
        
            % Plot Second Perpendicular Bisector
            xBisector2 = linspace(0, 100, 100);
            if ~isinf(mBisector2)
                yBisector2 = mBisector2 * xBisector2 + cBisector2;
            else
                xBisector2 = cBisector2 * ones(size(xBisector2));
                yBisector2 = linspace(0, 100, 100);
            end
            app.lineHandles(2) = plot(app.UIAxes, xBisector2, yBisector2, 'c--', 'LineWidth', 1.2, 'DisplayName', 'Second Perpendicular Bisector');
            
            % Circle Intersections
            intersections1 = intersections2circles(radiusCircle1, xCircle1, yCircle1, radiusCircle3, xCircle3, yCircle3);
            intersections2 = intersections2circles(radiusCircle2, xCircle2, yCircle2, radiusCircle3, xCircle3, yCircle3);
        
            % Animate the appearance of intersection points
            if ~isempty(intersections1)
                pause(0.5); % wait before showing them
                plot(app.UIAxes, intersections1(:, 1), intersections1(:, 2), 'ko', 'MarkerSize', 8, 'DisplayName', 'Intersections with Circle 1');
                drawnow;
            end
            if ~isempty(intersections2)
                pause(0.5); % wait before showing them
                plot(app.UIAxes, intersections2(:, 1), intersections2(:, 2), 'ko', 'MarkerSize', 8, 'DisplayName', 'Intersections with Circle 2');
                drawnow;
            end
            % Plot Intersections
            if ~isempty(intersections1)
                plot(app.UIAxes, intersections1(:, 1), intersections1(:, 2), 'ko', 'MarkerSize', 8, 'DisplayName', 'Intersections with Circle 1');
            end
            if ~isempty(intersections2)
                plot(app.UIAxes, intersections2(:, 1), intersections2(:, 2), 'ko', 'MarkerSize', 8, 'DisplayName', 'Intersections with Circle 2');
            end
        
            % Third Perpendicular Bisector (Intersection Points)
            if ~isempty(intersections1) && ~isempty(intersections2)
            % Take the first intersection point from each set
            xInt1 = intersections1(1, 1); % Intersection point from Circle 1 and Circle 3
            yInt1 = intersections1(1, 2);
            xInt2 = intersections2(1, 1); % Intersection point from Circle 2 and Circle 3
            yInt2 = intersections2(1, 2);
        
            % Compute the perpendicular bisector
            [mBisector3, cBisector3] = centreBisector(xInt1, yInt1, xInt2, yInt2);
        
            % Plot the third perpendicular bisector
            xBisector3 = linspace(0, 100, 100);
            if ~isinf(mBisector3)
                yBisector3 = mBisector3 * xBisector3 + cBisector3;
            else
                xBisector3 = cBisector3 * ones(size(xBisector3));
                yBisector3 = linspace(0, 100, 100);
            end
            app.lineHandles(3) = plot(app.UIAxes, xBisector3, yBisector3, '--', 'LineWidth', 1.2, 'DisplayName', 'Third Perpendicular Bisector');
            else
            uialert(app.UIFigure, 'Intersections for one or both circles are missing; cannot plot third bisector.', 'Error');
            end
        
            % Compute Transformation Matrices
            [matrixT, matrixR, matrixCombined] = transformationMatrices(xCircle1, yCircle1, xCircle2, yCircle2, xCircle3, yCircle3);
        
            % Display Matrices (for debugging)
            disp('Translation Matrix (T):');
            disp(matrixT);
            disp('Rotation Matrix (R):');
            disp(matrixR);
            disp('Combined Transformation Matrix:');
            disp(matrixCombined);
        
            % Apply Transformation to All Geometric Elements
            % Transform circles
            circle1Points = [x1; y1; ones(size(x1))];
            circle2Points = [x2; y2; ones(size(x2))];
            circle3Points = [x3; y3; ones(size(x3))];
        
            transformedCircle1 = matrixCombined * circle1Points;
            transformedCircle2 = matrixCombined * circle2Points;
            transformedCircle3 = matrixCombined * circle3Points;
        
            % Transform intersection points
            if ~isempty(intersections1)
                intersections1Homogeneous = [intersections1'; ones(1, size(intersections1, 1))];
                transformedIntersections1 = matrixCombined * intersections1Homogeneous;
            end
            if ~isempty(intersections2)
                intersections2Homogeneous = [intersections2'; ones(1, size(intersections2, 1))];
                transformedIntersections2 = matrixCombined * intersections2Homogeneous;
            end
            
        
            % Plot Transformed Elements
            % Extract transformed circle coordinates
            x1t = transformedCircle1(1,:);
            y1t = transformedCircle1(2,:);
            x2t = transformedCircle2(1,:);
            y2t = transformedCircle2(2,:);
            x3t = transformedCircle3(1,:);
            y3t = transformedCircle3(2,:);
            
            numStepsTrans = length(x1t);
            
            % Animate Transformed First Circle
            hCT1 = plot(app.UIAxes, x1t(1), y1t(1), 'b--', 'LineWidth', 1.2, 'DisplayName', 'Transformed First Circle');
            for seg = 2:numStepsTrans
                set(hCT1, 'XData', x1t(1:seg), 'YData', y1t(1:seg));
                drawnow;
                pause(0.001);
            end
            
            % Animate Transformed Second Circle
            hCT2 = plot(app.UIAxes, x2t(1), y2t(1), 'r--', 'LineWidth', 1.2, 'DisplayName', 'Transformed Second Circle');
            for seg = 2:numStepsTrans
                set(hCT2, 'XData', x2t(1:seg), 'YData', y2t(1:seg));
                drawnow;
                pause(0.001);
            end
            
            % Animate Transformed Third Circle
            hCT3 = plot(app.UIAxes, x3t(1), y3t(1), 'm--', 'LineWidth', 1.2, 'DisplayName', 'Transformed Third Circle');
            for seg = 2:numStepsTrans
                set(hCT3, 'XData', x3t(1:seg), 'YData', y3t(1:seg));
                drawnow;
                pause(0.001);
            end
            % Plot Transformed Intersection Points
            if exist('transformedIntersections1', 'var')
                plot(app.UIAxes, transformedIntersections1(1, :), transformedIntersections1(2, :), 'ko', 'MarkerSize', 8, 'DisplayName', 'Transformed Intersections with Circle 1');
            end
            if exist('transformedIntersections2', 'var')
                plot(app.UIAxes, transformedIntersections2(1, :), transformedIntersections2(2, :), 'ko', 'MarkerSize', 8, 'DisplayName', 'Transformed Intersections with Circle 2');
            end
            
        
            legend(app.UIAxes, 'show');
        
            hold(app.UIAxes, 'off');
            uialert(app.UIFigure, 'All circles, bisectors, and intersections constructed successfully!', 'Success');


        end

        % Button down function: UIAxes
        function UIAxesButtonDown(app, event)

            point = app.UIAxes.CurrentPoint(1, 1:2); 
            pointHomogeneous = [point, 1]; 

            app.points = [app.points; pointHomogeneous]; 

           
            if app.showPoints
                
                hold(app.UIAxes, 'on');

                plot(app.UIAxes, point(1), point(2), 'ro', 'MarkerSize', 8, 'DisplayName', 'Point');

            end
        end

        % Button pushed function: SaveButton
        function savePoints(app, event)

            if isempty(app.points)

                uialert(app.UIFigure, 'No points to save.', 'Error');

            return;

            end

            [fileName, filePath] = uiputfile('*.txt', 'Save Points');

            if fileName == 0

                return; 

            end
    
    
            fullFileName = fullfile(filePath, fileName);
    
            fileID = fopen(fullFileName, 'w');
    
            for i = 1:size(app.points, 1)
    
                fprintf(fileID, '%.6f %.6f\n', app.points(i, 1), app.points(i, 2));
    
            end
    
            fclose(fileID);
        
            uialert(app.UIFigure, 'Points saved successfully!', 'Success');
                    
        end

        % Button pushed function: LoadButton
        function loadPoints(app, event)
        
            [fileName, filePath] = uigetfile('*.txt', 'Load Points');
        
            if fileName == 0
        
                return; 
            end
        
         
            fullFileName = fullfile(filePath, fileName);
            loadedPoints = load(fullFileName);
        
            if size(loadedPoints, 2) ~= 2
                uialert(app.UIFigure, 'Invalid file format. The file must contain two columns for x and y coordinates.', 'Error');
                return;
            end
        
            app.points = loadedPoints;
            cla(app.UIAxes);
        
            hold(app.UIAxes, 'on');
        
            for i = 1:size(app.points, 1)
        
                plot(app.UIAxes, app.points(i, 1), app.points(i, 2), 'ro', 'MarkerSize', 8);
        
            end

            hold(app.UIAxes, 'off');
        
           
            Run(app);
        end

        % Button pushed function: Make3DButton
        function make3D(app, event)

            makeSphere(app);
            uialert(app.UIFigure, 'Circles have been converted into spheres!', 'Success');
    
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Circle GUI')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XLim = [0 100];
            app.UIAxes.YLim = [0 100];
            app.UIAxes.ZLim = [0 100];
            app.UIAxes.ButtonDownFcn = createCallbackFcn(app, @UIAxesButtonDown, true);
            app.HideRevealPointsButton = uibutton(app.UIFigure, 'push');
            app.HideRevealPointsButton.ButtonPushedFcn = createCallbackFcn(app, @PointsButton, true);
            app.HideRevealPointsButton.Text = 'Hide/Reveal Points';
            app.HideRevealLinesButton = uibutton(app.UIFigure, 'push');
            app.HideRevealLinesButton.ButtonPushedFcn = createCallbackFcn(app, @LinesButton, true);
            app.HideRevealLinesButton.Text = 'Hide/Reveal Lines';
            app.HideRevealConstructionButton = uibutton(app.UIFigure, 'push');
            app.HideRevealConstructionButton.ButtonPushedFcn = createCallbackFcn(app, @ConstructionButton, true);
            app.HideRevealConstructionButton.Text = 'Hide/Reveal Construction';
            app.RunConstructionButton = uibutton(app.UIFigure, 'push');
            app.RunConstructionButton.ButtonPushedFcn = createCallbackFcn(app, @Run, true);
            app.RunConstructionButton.Text = 'Run Construction';
            app.SaveButton = uibutton(app.UIFigure, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @savePoints, true);
            app.SaveButton.Text = 'Save';
            app.LoadButton = uibutton(app.UIFigure, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @loadPoints, true);
            app.LoadButton.Text = 'Load';
            app.Make3DButton = uibutton(app.UIFigure, 'push');
            app.Make3DButton.ButtonPushedFcn = createCallbackFcn(app, @make3D, true);
            app.Make3DButton.Text = 'Make 3D';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = App

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end